classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UploadvecfileButton           matlab.ui.control.Button
        CheckBox                      matlab.ui.control.CheckBox
        direc_y                       matlab.ui.control.NumericEditField
        direc_x                       matlab.ui.control.NumericEditField
        ORDirectionVectorEditFieldLabel  matlab.ui.control.Label
        NoiseParamEditField           matlab.ui.control.NumericEditField
        NoiseParamEditFieldLabel      matlab.ui.control.Label
        RunButton                     matlab.ui.control.Button
        InsideOutsideThresholdSlider  matlab.ui.control.Slider
        Label_2                       matlab.ui.control.Label
        ofDirectionsSlider            matlab.ui.control.Slider
        Label                         matlab.ui.control.Label
        ofSamplePointsSlider          matlab.ui.control.Slider
        ofSamplePointsSliderLabel     matlab.ui.control.Label
        ShapeDropDown                 matlab.ui.control.DropDown
        ShapeDropDownLabel            matlab.ui.control.Label
        UITriCont                     matlab.ui.control.UIAxes
        UIGradPlot                    matlab.ui.control.UIAxes
        UISolPlot                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        vecFile % path to uploaded vec file
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % val = app.Slider.Value; % Get
            % app.Gauge.Value = val;

            cla(app.UIGradPlot, 'reset')
            cla(app.UISolPlot, 'reset')
            cla(app.UITriCont, 'reset')

            n = round(app.ofSamplePointsSlider.Value);r = 0.01; % frequency and radius of cage vertices
            t = linspace(0, 2*pi, 1000); % parameter value
            using_vec = 1;
            shape = app.ShapeDropDown.Value;
            switch shape
                case "Input .vec"
                    % vecFile = strcat(app.UploadVecFileEditField.Value, '.vec');
                    vecRead = xmlread(app.vecFile);
                    using_vec = 0;
                    
                    sketch_edges = vecRead.getElementsByTagName('edge');
                    x_shape = [];
                    y_shape = [];
                    
                    for i = 0:sketch_edges.getLength-1
                        edge = sketch_edges.item(i);
                        % the xywdense has format: xywdense(layer(int) x1, y1, w1, x2, y2, w2, ...);
                        densesample = char(edge.getAttribute('curve'));
                        sample_substring = regexp(densesample, '[+-]?\d*\.?\d+', 'match');
                        sample_double = str2double(sample_substring);
                    
                        length_x = length(sample_double(2:3:end));
                        length_y = length(sample_double(3:3:end));
                    
                        x_indices = round(linspace(1, length_x, n));
                        y_indices = round(linspace(1, length_y, n));
                        
                        x_sample = sample_double(2:3:end);
                        x_to_add = x_sample(x_indices);
                        y_sample = sample_double(3:3:end);
                        y_to_add = y_sample(y_indices);
                    
                        x_shape = [x_shape, x_sample];
                        y_shape = [y_shape, y_sample];
                    end
                    min_x = min(x_shape);
                    max_x = max(x_shape);
                    min_y = min(y_shape);
                    max_y = max(y_shape);
                    x_scale = (3 - (-3))/(max_x - min_x);
                    y_scale = (3 - (-3))/(max_y - min_y);
                    x_shape = x_scale*x_shape;
                    y_shape = -y_scale*y_shape;

                case "Heart"
                    x_shape = 0.08*(16*sin(t).^3);
                    y_shape = 0.08*(13*cos(t) - 5*cos(2*t) - 2*cos(3*t) - cos(4*t));
                case "Diamond"
                    a = 0.7;
                    x_shape = a*cos(t).^3;
                    y_shape = a*sin(t).^3;
                case "Rose"
                    a = 0.6;
                    petals = 4;
                    x_shape = a*cos(petals.*t).*cos(t);
                    y_shape = a*cos(petals.*t).*sin(t);
                otherwise
                    radius = 0.75;
                    x_shape = radius*cos(t);
                    y_shape = radius*sin(t);
            end

            noise_param = app.NoiseParamEditField.Value;
            p = [x_shape', y_shape'];
            q = curvspace(p,n); % generates points that interpolate curve
            xx = q(:, 1) + normrnd(0, 1, [size(q, 1), 1])*noise_param; % x values of curve
            yy = q(:, 2) + normrnd(0, 1, [size(q, 1), 1])*noise_param; % y values of curve
            if using_vec == 0
                xx = p(:, 1)+ normrnd(0, 1, [size(p, 1), 1])*noise_param;
                yy = p(:, 2) + normrnd(0, 1, [size(p, 1), 1])*noise_param;;
            end
            c = xx + 1i*yy; % curve as vector of complex coordinates
            
            rr = r*ones(size(c)); % radii of cage vertices as vector
            N = max(0, round(4+.5*log10(r)));
            npts=3*N+2;

            z = exp(pi*1i*(-10:10)'/10); % vector of circle coordinates for cage vertices
            min_x = min(x_shape);
            max_x = max(x_shape);
            min_y = min(y_shape);
            max_y = max(y_shape);
            x = [min_x-2, max_x+2, max_x+2, min_x-2]; % boundary of region
            y = [min_y-2, min_y-2, max_y+2, max_y+2]; % boundary of region
            pgon = polyshape({x, real(c(1)+rr(1)*z)}, {y, imag(c(1)+rr(1)*z)}); 
            for j = 2:length(c)-1
                % adds another hole for each cage vertex %
                pgon = addboundary(pgon,real(c(j)+rr(j)*z),imag(c(j)+rr(j)*z));
            end

            tr = triangulation(pgon);
            points = tr.Points; %row number is vertex id
            connectivity = tr.ConnectivityList;

            gm = fegeometry(tr);
            gm = generateMesh(gm, Hmax = 0.5, Hmin= 0.001);
            numelements = size(gm.Mesh.Elements, 2);
            numnodes = size(gm.Mesh.Nodes, 2);
                    % elements is 6 x (number of triangles)
                    % the vertex ids of the triangles are in the first three rows of gm.Mesh.Elements
                    % midpoints of the edges of the triangle are the last 3 rows of the gm.Mesh.Elements
                    % vertices of the element(triangle) are the first 3 rows of gm.Mesh.Elements
            
            % this finds which vertices of our mesh lie on a cage vertex:
            cagePts = findNodes(gm.Mesh,"radius",[real(c(1)), imag(c(1))], rr(1));
            for j = 1:length(c)-1
                cagePts = [cagePts findNodes(gm.Mesh,"radius",[real(c(j)), imag(c(j))], rr(j))];
            end

            boundaryPts = findNodes(gm.Mesh, "region", "Edge",[1 2 3 4]);

            neighborsMatrix = sparse(numnodes, numnodes);
            %neighborsMatrix(i, j) = neighborsMatrix(j, i) == 1 if vertex i and j share an edge, and 0 otherwise
            cotangents = sparse(numnodes, numnodes);
            % cotangents(i, j) = 1/2*cot(alpha(i, j)) + cot(beta(i, j))
            for i = 1:numelements %iterate through the triangles
              v1_id = gm.Mesh.Elements(1, i); v2_id = gm.Mesh.Elements(2, i); v3_id = gm.Mesh.Elements(3, i); %get vertex id's of the triangle
              v1 = gm.Mesh.Nodes(:, v1_id);v2 = gm.Mesh.Nodes(:, v2_id);v3 = gm.Mesh.Nodes(:, v3_id); % get vertex locations
              neighborsMatrix(v1_id, v2_id) = 1;neighborsMatrix(v2_id, v3_id) = 1;neighborsMatrix(v3_id, v1_id) = 1;
              neighborsMatrix(v2_id, v1_id) = 1;neighborsMatrix(v3_id, v2_id) = 1;neighborsMatrix(v1_id, v3_id) = 1;
              cotangents(v1_id, v2_id) = cotangents(v1_id, v2_id) + 0.5*cot(acos(dot(v1-v3, v2-v3)/(norm(v1-v3)*norm(v2-v3))));
              cotangents(v2_id, v1_id) = cotangents(v1_id, v2_id);
            
              cotangents(v2_id, v3_id) = cotangents(v2_id, v3_id) + 0.5*cot(acos(dot(v2-v1, v3-v1)/(norm(v2-v1)*norm(v3-v1))));
              cotangents(v3_id, v2_id) = cotangents(v2_id, v3_id);
            
              cotangents(v3_id, v1_id) = cotangents(v3_id, v1_id) + 0.5*cot(acos(dot(v3-v2, v1-v2)/(norm(v3-v2)*norm(v1-v2))));
              cotangents(v1_id, v3_id) = cotangents(v3_id, v1_id);
            end
            neighborsMatrix = neighborsMatrix(any(neighborsMatrix, 2), :); %removing rows of the middle point nodes
            neighborsMatrix = neighborsMatrix(:, any(neighborsMatrix, 1)); %removing columns of the middle point nodes
            cotangents = cotangents(any(cotangents, 2), :); %removing rows of the middle point nodes
            cotangents = cotangents(:, any(cotangents, 1)); %removing columns of the middle point nodes
            
            %updates numnodes to exclude midpoints
            numnodes = size(neighborsMatrix, 1);
            
            % updates cagePts and boundaryPts to remove any midpoint vertices
            colstodelete = cagePts > numnodes;
            cagePts(colstodelete) = [];
            colstodelete = boundaryPts > numnodes;
            boundaryPts(colstodelete) = [];
            
            % creating cotangent Laplacian
            Laplacian_cotangents = sparse(numnodes, numnodes);
            for i = 1:numnodes
                normalize_value2 = sum(cotangents(i, :));
                Laplacian_cotangents(i, :) = -cotangents(i, :);
                Laplacian_cotangents(i,i) = normalize_value2;
            end

            
            C = sparse(numnodes, numnodes);
            % C(i, i) = 1 if vertex i is on the boundary
            for i =1:size(boundaryPts, 2)
                C(boundaryPts(i), boundaryPts(i)) = 1;
            end

            num_cagepts = size(cagePts, 2);
            for i = 1:num_cagepts
                next = mod(i, num_cagepts)+1;
                C(cagePts(i), cagePts(i)) = -1;
                C(cagePts(i), cagePts(next)) = 1;
            end
            
            C = C(any(C, 2), :);
            a = zeros(size(C, 1), 1);

            % coordinates of the boundary points
            boundaryPts_loc = gm.Mesh.Nodes(:, boundaryPts);

            num_direcs = round(app.ofDirectionsSlider.Value);    % num directions to test
            fieldvec_mag = 1;
            u_directions = [];
            for j = 1:num_direcs
                u_directions = [u_directions; fieldvec_mag*cos(j*2*pi/num_direcs) fieldvec_mag*sin(j*2*pi/num_direcs)];
            end
            if app.CheckBox.Value == 1
                num_direcs = 1;
                u_directions = [app.direc_x.Value, app.direc_y.Value];
            end
            sol = zeros(numnodes, num_direcs);
            for i=1:num_direcs
                boundaryPts_constraints = u_directions(i,:)*boundaryPts_loc;
                a(boundaryPts) = boundaryPts_constraints;

                LHS = [Laplacian_cotangents, C.'; 
                C, sparse(size(C, 1), size(C, 1))];
                RHS = [zeros(numnodes, 1); a];
                x_v = LHS\RHS;
                
                sol(:, i) = x_v(1:numnodes);
            end

            patch_x = []; %holds the x values of the triangle vertices
            patch_y = []; %holds the y values of the triangle vertices
            patch_color = []; %holds the interpolated value of sol of the patch
            face_gradient = []; % holds the magnitude of the face gradient of the patch
            for i = 1:numelements
                v1_id = gm.Mesh.Elements(1, i);v2_id = gm.Mesh.Elements(2, i);v3_id = gm.Mesh.Elements(3, i);
                v1 = gm.Mesh.Nodes(:, v1_id);v2 = gm.Mesh.Nodes(:, v2_id);v3 = gm.Mesh.Nodes(:, v3_id);
            
                patch_x = [patch_x, [v1(1);v2(1);v3(1)]];
                patch_y = [patch_y, [v1(2);v2(2);v3(2)]];
            
                A = [(v1-v2); 0];
                B = [(v1-v3); 0];
                twoAT = norm(cross(A, B));
            
                B2 = (v1-v3)/twoAT;B2 = [-B2(2), B2(1)];
                B3 = (v2-v1)/twoAT;B3 = [-B3(2), B3(1)];
            
                for i = 1:num_direcs
                    grad_face = (sol(v2_id, i) - sol(v1_id, i))*B2 + (sol(v3_id, i)-sol(v1_id, i))*B3;
                    if i == 1
                        norm_grad = norm(grad_face);
                    else
                        norm_grad = max(norm_grad, norm(grad_face));
                    end
                end
            
                face_gradient = [face_gradient; norm_grad];
                patch_color = [patch_color, [sol(v1_id, 1); sol(v2_id, 1); sol(v3_id, 1)]];
            end

            exp_quantiles = quantile(face_gradient, [0.025, 0.75], "all");
            toobig = face_gradient>exp_quantiles(2);
            face_gradient(toobig) = exp_quantiles(2);
            
            threshold = app.InsideOutsideThresholdSlider.Value;
            bottom_15_percentile = quantile(face_gradient, 0.01*threshold);
            inside_outside = double(face_gradient <= bottom_15_percentile);
            
            
            figure;
            patch(app.UISolPlot, patch_x, patch_y, patch_color);
            title(app.UISolPlot, 'KKT Solve');
            
            figure;
            patch(app.UIGradPlot, patch_x, patch_y, face_gradient);
            title(app.UIGradPlot, 'Max Mag Grad');

            vertex_values = zeros(numnodes, 1); % Initialize vertex values
            vertex_counts = zeros(numnodes, 1); % To keep track of how many faces contribute to each vertex
            

            for i = 1:size(inside_outside)
                face = gm.Mesh.Elements(1:3, i);
            
                v1 = gm.Mesh.Nodes(:, face(1))';v2 = gm.Mesh.Nodes(:, face(2))';v3 = gm.Mesh.Nodes(:, face(3))';
            
                face_area = 0.5 * abs(det([v1, 1; v2, 1; v3, 1]));
            
                vertex_values(face) = vertex_values(face) + inside_outside(i)*face_area; % Accumulate face value for vertices
                vertex_counts(face) = vertex_counts(face) + face_area; % Count contributions to each vertex
            end
            vertex_values = vertex_values ./ vertex_counts;
            
            figure;
            tricontf(app.UITriCont, gm.Mesh.Nodes(1,1:numnodes)', gm.Mesh.Nodes(2,1:numnodes)', gm.Mesh.Elements(1:3, :)', vertex_values, [0.5 0.5], 'k');
            title(app.UITriCont, 'inside/outside')
        end

        % Button pushed function: UploadvecfileButton
        function UploadvecfileButtonPushed(app, event)
            [file,path] = uigetfile('*.vec');
            if isequal(file, 0)
                uialert(app.UIFigure, 'No file selected!', 'Error');
            else
                app.vecFile = fullfile(path, file);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UISolPlot = uiaxes(app.UIFigure);
            title(app.UISolPlot, 'KKT Solve')
            app.UISolPlot.XLim = [-3 3];
            app.UISolPlot.YLim = [-3 3];
            app.UISolPlot.XTick = [-3 -2 -1 0 1 2 3];
            app.UISolPlot.XTickLabel = {'-3'; '-2'; '-1'; '0'; '1'; '2'; '3'};
            app.UIGradPlot = uiaxes(app.UIFigure);
            title(app.UIGradPlot, 'Max Mag Grad')
            app.UIGradPlot.XLim = [-3 3];
            app.UIGradPlot.YLim = [-3 3];
            app.UIGradPlot.XTick = [-3 -2 -1 0 1 2 3];
            app.UIGradPlot.XTickLabel = {'-3'; '-2'; '-1'; '0'; '1'; '2'; '3'};
            app.UIGradPlot.YTick = [-3 -2 -1 0 1 2 3];
            app.UIGradPlot.YTickLabel = {'-3'; '-2'; '-1'; '0'; '1'; '2'; '3'};
            app.UITriCont = uiaxes(app.UIFigure);
            title(app.UITriCont, 'Inside/Outside TriCont')
            app.UITriCont.XLim = [-3 3];
            app.UITriCont.YLim = [-3 3];
            app.UITriCont.XTick = [-3 -2 -1 0 1 2 3];
            app.UITriCont.XTickLabel = {'-3'; '-2'; '-1'; '0'; '1'; '2'; '3'};
            app.UITriCont.YTick = [-3 -2 -1 0 1 2 3];
            app.UITriCont.YTickLabel = {'-3'; '-2'; '-1'; '0'; '1'; '2'; '3'};
            app.ShapeDropDownLabel = uilabel(app.UIFigure);
            app.ShapeDropDownLabel.Text = 'Shape';
            app.ShapeDropDown = uidropdown(app.UIFigure);
            app.ShapeDropDown.Items = {'Circle', 'Heart', 'Diamond', 'Rose', 'Input .vec'};
            app.ShapeDropDown.Value = 'Circle';
            app.ofSamplePointsSliderLabel = uilabel(app.UIFigure);
            app.ofSamplePointsSliderLabel.Text = '# of Sample Points';
            app.ofSamplePointsSlider = uislider(app.UIFigure);
            app.ofSamplePointsSlider.Value = 15;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '# of Directions';
            app.ofDirectionsSlider = uislider(app.UIFigure);
            app.ofDirectionsSlider.Limits = [0 20];
            app.ofDirectionsSlider.Value = 5;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Inside/Outside Threshold';
            app.InsideOutsideThresholdSlider = uislider(app.UIFigure);
            app.InsideOutsideThresholdSlider.Value = 15;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run!';
            app.NoiseParamEditFieldLabel = uilabel(app.UIFigure);
            app.NoiseParamEditFieldLabel.Text = 'Noise Param';
            app.NoiseParamEditField = uieditfield(app.UIFigure, 'numeric');
            app.ORDirectionVectorEditFieldLabel = uilabel(app.UIFigure);
            app.ORDirectionVectorEditFieldLabel.Text = 'OR Direction Vector';
            app.direc_x = uieditfield(app.UIFigure, 'numeric');
            app.direc_x.AllowEmpty = 'on';
            app.direc_x.Value = [];
            app.direc_y = uieditfield(app.UIFigure, 'numeric');
            app.direc_y.AllowEmpty = 'on';
            app.direc_y.Value = [];
            app.CheckBox = uicheckbox(app.UIFigure);
            app.UploadvecfileButton = uibutton(app.UIFigure, 'push');
            app.UploadvecfileButton.ButtonPushedFcn = createCallbackFcn(app, @UploadvecfileButtonPushed, true);
            app.UploadvecfileButton.Text = 'Upload .vec file';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end